classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KhmerPalmLeafManuscriptBinarizationUIFigure  matlab.ui.Figure
        Button_2                  matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        CloseButton               matlab.ui.control.Button
        SaveSSPImageButton        matlab.ui.control.Button
        LoadImagePanel            matlab.ui.container.Panel
        SaveEnhanceImageButton    matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        CropButton                matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        StructureElementTab       matlab.ui.container.Tab
        RemovinglittlenoiseLabel  matlab.ui.control.Label
        SizeSpinner               matlab.ui.control.Spinner
        SizeSpinnerLabel          matlab.ui.control.Label
        PreviewButton             matlab.ui.control.Button
        ColumnSpinner             matlab.ui.control.Spinner
        ColumnSpinnerLabel        matlab.ui.control.Label
        RowSpinner                matlab.ui.control.Spinner
        RowSpinnerLabel           matlab.ui.control.Label
        ApplyButton               matlab.ui.control.Button
        FlatfieldTab              matlab.ui.container.Tab
        PreviewFlatFieldButton    matlab.ui.control.Button
        ApplyFlatFieldButton      matlab.ui.control.Button
        SigmaSpinner              matlab.ui.control.Spinner
        SigmaSpinnerLabel         matlab.ui.control.Label
        BrightenTab               matlab.ui.container.Tab
        ApplyBrightnessButton     matlab.ui.control.Button
        PreviewBrightnessButton   matlab.ui.control.Button
        AlphaBlendCheckBox        matlab.ui.control.CheckBox
        brightnessSpinner         matlab.ui.control.Spinner
        brightnessSpinnerLabel    matlab.ui.control.Label
        EnhancingimageLabel       matlab.ui.control.Label
        ImageNameLabel            matlab.ui.control.Label
        EnhanceImage              matlab.ui.control.Image
        BrowseButton              matlab.ui.control.Button
        StructuralSymmetryofStrokesPanel  matlab.ui.container.Panel
        ApplyButton_2             matlab.ui.control.Button
        SSPImage                  matlab.ui.control.Image
        StrokeSlider              matlab.ui.control.Slider
        StrokeSliderLabel         matlab.ui.control.Label
        AlphaSlider               matlab.ui.control.Slider
        AlphaSliderLabel          matlab.ui.control.Label
        AlphaSpinner              matlab.ui.control.Spinner
        KSpinner                  matlab.ui.control.Spinner
        StatusMessageLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        TempImage = '' % Temp image file
    end
    
    methods (Access = private)
        
        function result = SSP(app, Im)
            result = '';
            if isfile('bin\ssp.exe')
                TempName = [tempname, '.jpg'];
                imwrite(Im, TempName);
                Alpha = app.AlphaSpinner.Value;
                K = app.KSpinner.Value;
                Rthre = app.RthreSpinner.Value;                
                command = sprintf('bin\\ssp.exe %s %s %d %.2f %.2f', TempName, TempName, Alpha, K, Rthre);
                fprintf('Running command => %s\n', command)
                [status, ~] = system(command);
                if (status == 0)
                    disp('SSP: success')
                    result = imread(TempName);
                else
                    fprintf('SSP: error. command=%s\n', command)
                end
            else
                disp('SSP library not found')
            end
        end
        
        function statusMsg(app, varargin)
            disp(nargin);
            if nargin < 1
                error('statusMsg : message is a required')
            end
            if nargin >= 2
                message = varargin{1};
                app.StatusMessageLabel.Text = message;
            end
            if nargin >= 3
                type = varargin{2};
                switch type
                    case 'error'
                        app.StatusMessageLabel.FontColor = [1 0 0];
                    case 'warn'
                        app.StatusMessageLabel.FontColor = [1 1 0]; 
                    case 'info'
                        app.StatusMessageLabel.FontColor = [0.33 0.72 0.06];
                    otherwise
                        app.StatusMessageLabel.FontColor = [0 0 0];
                end
            end
        end
        
        function showImage(app, UiImage, Image)
            if isfile(app.TempImage)
                delete(app.TempImage)
            end
            app.TempImage = [tempname, '.jpg'];
            imwrite(Image, app.TempImage);
            UiImage.ImageSource = app.TempImage;
        end
        
        function isValid = isValidImage(app, Image)
            [~, ~, D] = size(Image);
            if ~isempty(Image) && (D == 3 || ismatrix(Image))
                isValid = true;
            end
            isValid = false;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear global;
            %movegui(app.KhmerPalmLeafManuscriptBinarizationUIFigure,'center');
            app.KSpinner.Limits = app.StrokeSlider.Limits;
            %app.StatusMessageLabel.Text = num2str(app.KSpinner.Limits);
        end

        % Value changed function: StrokeSlider
        function StrokeSliderValueChanged(app, event)
            value = app.StrokeSlider.Value;
            app.KSpinner.Value = value;
        end

        % Value changing function: StrokeSlider
        function StrokeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.KSpinner.Value = changingValue;
        end

        % Value changed function: KSpinner
        function KSpinnerValueChanged(app, event)
            value = app.KSpinner.Value;
            app.StrokeSlider.Value = value;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            global ImageOriginal ImageEnhance;
            [file,path,~] = uigetfile({'*.png;*.jpg;*.jpeg', 'Image file (*.png,*.jpg,*.jpeg)'}, 'Please select an image');
            figure(app.KhmerPalmLeafManuscriptBinarizationUIFigure);%to keep focus after seleced a file
            if isequal(file,0)
%                 errordlg('File not found','File Error');
                app.statusMsg('User selected Cancel', 'info');
            else
                ImageOriginal = imread(fullfile(path, file));
                ImageEnhance = ImageOriginal;
                app.ImageNameLabel.Text = file;
                app.EnhanceImage.ImageSource = fullfile(path, file);
                app.TabGroup.Visible = 'on';
                app.Button.Enable = 'on';
                app.ResetButton.Enable = 'on';
                app.CropButton.Enable = 'on';
                app.SaveEnhanceImageButton.Enable = 'on';
%                 app.StatusMessageLabel.Text = num2str(['User selected ', ImageOriginal]);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global ImageEnhance ImageSSP;           
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (D == 3 || ismatrix(ImageEnhance))
                ImageSSP = ImageEnhance;
                app.showImage(app.SSPImage, ImageSSP)
                app.Button_2.Enable = 'on';
                app.SaveSSPImageButton.Enable = 'on';
                app.ApplyButton_2.Enable = 'on';
            else
                app.statusMsg('Image is invalid', 'error');
            end
            set(app.KhmerPalmLeafManuscriptBinarizationUIFigure, 'pointer', 'arrow');drawnow;
        end

        % Button pushed function: SaveSSPImageButton
        function SaveSSPImageButtonPushed(app, event)
            global ImageSSP;
            [~, ~, D] = size(ImageSSP);
            if ~isempty(ImageSSP) && (D == 3 || ismatrix(ImageSSP))
                [filename,~,user_canceled] = imputfile;
                if ~user_canceled
                    imwrite(ImageSSP, filename)
                    figure(app.KhmerPalmLeafManuscriptBinarizationUIFigure);
                    app.statusMsg(sprintf('Image saved in: %s', filename));
                end
            end
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            global ImageSSP;
            try
                set(app.KhmerPalmLeafManuscriptBinarizationUIFigure, 'pointer', 'watch');drawnow;
                [~, ~, D] = size(ImageSSP);
                if ~isempty(ImageSSP) && (D == 3 || ismatrix(ImageSSP))
                    ImageSSP = app.SSP(ImageSSP);
                    app.showImage(app.SSPImage, ImageSSP);
                end
            catch ME
                app.statusMsg(ME.message, 'error');
                disp(getReport(ME, 'extended', 'hyperlinks', 'on' ));
            end
            set(app.KhmerPalmLeafManuscriptBinarizationUIFigure, 'pointer', 'arrow');drawnow;     
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global ImageSSP ImageEnhance;
            [~, ~, D] = size(ImageSSP);
            if ~isempty(ImageSSP) && (D == 3 || ismatrix(ImageSSP))
                ImageEnhance = ImageSSP;
                app.showImage(app.EnhanceImage, ImageEnhance);
            else
                app.statusMsg('ImageSSP is not found', 'error');
            end
        end

        % Value changed function: AlphaSpinner
        function AlphaSpinnerValueChanged(app, event)
            value = app.AlphaSpinner.Value;
            app.AlphaSlider.Value = value;
        end

        % Callback function
        function AlphaSliderValueChanged(app, event)
           
        end

        % Value changing function: AlphaSlider
        function AlphaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.AlphaSpinner.Value = changingValue;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            close(app.KhmerPalmLeafManuscriptBinarizationUIFigure);
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            global ImageEnhance;
            [ImCropped, ~] = imcrop(ImageEnhance);
            [~, ~, D] = size(ImCropped);
            if ~isempty(ImCropped) && (ismatrix(ImCropped) || D == 3)
                ImageEnhance = ImCropped;
                app.showImage(app.EnhanceImage, ImageEnhance);
            end
            close gcf
        end

        % Button pushed function: ApplyFlatFieldButton
        function ApplyFlatFieldButtonPushed(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (ismatrix(ImageEnhance) || D == 3)
                ImageEnhance = imflatfield(ImageEnhance, app.SigmaSpinner.Value);
                app.showImage(app.EnhanceImage, ImageEnhance);
            end
        end

        % Button pushed function: PreviewFlatFieldButton
        function PreviewFlatFieldButtonPushed(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (ismatrix(ImageEnhance) || D == 3)
                imshow(imflatfield(ImageEnhance, app.SigmaSpinner.Value));
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global ImageOriginal ImageEnhance;
            [~, ~, D] = size(ImageOriginal);
            if ~isempty(ImageOriginal) && (ismatrix(ImageOriginal) || D == 3)
                app.showImage(app.EnhanceImage, ImageOriginal);
                ImageEnhance = ImageOriginal;
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (ismatrix(ImageEnhance) || D == 3)
                Im = imbinarize(ImageEnhance);
                
                SE = strel('rectangle', [app.RowSpinner.Value app.ColumnSpinner.Value]);
                Id = imdilate(Im, SE);
                Ie = imerode(Id, SE);
                Im = (Im - Ie) == 0;
                
                if app.SizeSpinner.Value > 0
                    SE = strel('disk', app.SizeSpinner.Value);
                    Id = imdilate(Im, SE);
                    Ie = imerode(Id, SE);
                    Im = Ie;
                end
                
                Im = uint8(Im(:,:,[1 1 1]) * 255);%bw to RGB
                ImageEnhance = Im;
                app.showImage(app.EnhanceImage, ImageEnhance);
            else
                app.statusMsg('invalid image', 'error');
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);disp(D);
            if ~isempty(ImageEnhance) && (ismatrix(ImageEnhance) || D == 3)
                Im = imbinarize(ImageEnhance);
                SE = strel('rectangle', [app.RowSpinner.Value app.ColumnSpinner.Value]);
                Id = imdilate(Im, SE);
                Ie = imerode(Id, SE);
                Im = (Im - Ie) == 0;
                
                if app.SizeSpinner.Value > 0
                    SE = strel('disk', app.SizeSpinner.Value);
                    Id = imdilate(Im, SE);
                    Ie = imerode(Id, SE);
                    Im = Ie;
                end
                
                Im = uint8(Im(:,:,[1 1 1]) * 255);%bw to RGB
                imshow(Im);
            end
        end

        % Image clicked function: SSPImage
        function SSPImageClicked(app, event)
            global ImageSSP;
            [~, ~, D] = size(ImageSSP);
            if ~isempty(ImageSSP) && (ismatrix(ImageSSP) || D == 3)
                imshow(ImageSSP);
            end
        end

        % Image clicked function: EnhanceImage
        function EnhanceImageClicked(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (ismatrix(ImageEnhance) || D == 3)
                imshow(ImageEnhance);
            else
                app.statusMsg('invalid image')
            end
        end

        % Button pushed function: SaveEnhanceImageButton
        function SaveEnhanceImageButtonPushed(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (D == 3 || ismatrix(ImageEnhance))
                [filename,~,user_canceled] = imputfile;
                if ~user_canceled
                    imwrite(ImageEnhance, filename);
                    figure(app.KhmerPalmLeafManuscriptBinarizationUIFigure);
                    app.statusMsg(sprintf('Image saved in: %s', filename));
                end
            end
        end

        % Button pushed function: PreviewBrightnessButton
        function PreviewBrightnessButtonPushed(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (ismatrix(ImageEnhance) || D == 3)
                imshow(imlocalbrighten(ImageEnhance, app.brightnessSpinner.Value, 'AlphaBlend', app.AlphaBlendCheckBox.Value));
            end
        end

        % Button pushed function: ApplyBrightnessButton
        function ApplyBrightnessButtonPushed(app, event)
            global ImageEnhance;
            [~, ~, D] = size(ImageEnhance);
            if ~isempty(ImageEnhance) && (ismatrix(ImageEnhance) || D == 3)
                ImageEnhance = imlocalbrighten(ImageEnhance, app.brightnessSpinner.Value, 'AlphaBlend', app.AlphaBlendCheckBox.Value);
                app.showImage(app.EnhanceImage, ImageEnhance);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KhmerPalmLeafManuscriptBinarizationUIFigure = uifigure('Visible', 'off');
            app.KhmerPalmLeafManuscriptBinarizationUIFigure.Name = 'Khmer Palm Leaf Manuscript Binarization';
            app.StatusMessageLabel = uilabel(app.KhmerPalmLeafManuscriptBinarizationUIFigure);
            app.StatusMessageLabel.Text = 'Status Message';
            app.StructuralSymmetryofStrokesPanel = uipanel(app.KhmerPalmLeafManuscriptBinarizationUIFigure);
            app.StructuralSymmetryofStrokesPanel.Title = 'Structural Symmetry of Strokes';
            app.KSpinner = uispinner(app.StructuralSymmetryofStrokesPanel);
            app.KSpinner.Step = 0.1;
            app.KSpinner.Limits = [0 1];
            app.KSpinner.ValueDisplayFormat = '%.2f';
            app.KSpinner.ValueChangedFcn = createCallbackFcn(app, @KSpinnerValueChanged, true);
            app.KSpinner.Value = 0.5;
            app.AlphaSpinner = uispinner(app.StructuralSymmetryofStrokesPanel);
            app.AlphaSpinner.Step = 0.1;
            app.AlphaSpinner.Limits = [0 100];
            app.AlphaSpinner.ValueDisplayFormat = '%.2f';
            app.AlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @AlphaSpinnerValueChanged, true);
            app.AlphaSpinner.Value = 2;
            app.AlphaSliderLabel = uilabel(app.StructuralSymmetryofStrokesPanel);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.StructuralSymmetryofStrokesPanel);
            app.AlphaSlider.Limits = [0 50];
            app.AlphaSlider.ValueChangingFcn = createCallbackFcn(app, @AlphaSliderValueChanging, true);
            app.AlphaSlider.Tooltip = {'Alpha is a coefficient of threshold'};
            app.StrokeSliderLabel = uilabel(app.StructuralSymmetryofStrokesPanel);
            app.StrokeSliderLabel.Text = 'Stroke';
            app.StrokeSlider = uislider(app.StructuralSymmetryofStrokesPanel);
            app.StrokeSlider.Limits = [0 5];
            app.StrokeSlider.MajorTicks = [0 1 2 3 4 5];
            app.StrokeSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5'};
            app.StrokeSlider.ValueChangedFcn = createCallbackFcn(app, @StrokeSliderValueChanged, true);
            app.StrokeSlider.ValueChangingFcn = createCallbackFcn(app, @StrokeSliderValueChanging, true);
            app.StrokeSlider.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.StrokeSlider.Tooltip = {'Considering the text stroke thicker or thinner'};
            app.StrokeSlider.Value = 0.5;
            app.SSPImage = uiimage(app.StructuralSymmetryofStrokesPanel);
            app.SSPImage.ImageClickedFcn = createCallbackFcn(app, @SSPImageClicked, true);
            app.SSPImage.Tooltip = {''};
            app.ApplyButton_2 = uibutton(app.StructuralSymmetryofStrokesPanel, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Enable = 'off';
            app.ApplyButton_2.Text = 'Apply';
            app.LoadImagePanel = uipanel(app.KhmerPalmLeafManuscriptBinarizationUIFigure);
            app.LoadImagePanel.Title = 'Load Image';
            app.BrowseButton = uibutton(app.LoadImagePanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.EnhanceImage = uiimage(app.LoadImagePanel);
            app.EnhanceImage.ImageClickedFcn = createCallbackFcn(app, @EnhanceImageClicked, true);
            app.EnhanceImage.Tooltip = {''};
            app.ImageNameLabel = uilabel(app.LoadImagePanel);
            app.ImageNameLabel.Text = '...';
            app.EnhancingimageLabel = uilabel(app.LoadImagePanel);
            app.EnhancingimageLabel.Text = 'Enhancing image';
            app.TabGroup = uitabgroup(app.LoadImagePanel);
            app.StructureElementTab = uitab(app.TabGroup);
            app.StructureElementTab.Title = 'Structure Element';
            app.ApplyButton = uibutton(app.StructureElementTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.RowSpinnerLabel = uilabel(app.StructureElementTab);
            app.RowSpinnerLabel.Text = 'Row';
            app.RowSpinner = uispinner(app.StructureElementTab);
            app.RowSpinner.Limits = [1 Inf];
            app.RowSpinner.Value = 3;
            app.ColumnSpinnerLabel = uilabel(app.StructureElementTab);
            app.ColumnSpinnerLabel.Text = 'Column';
            app.ColumnSpinner = uispinner(app.StructureElementTab);
            app.ColumnSpinner.Limits = [2 Inf];
            app.ColumnSpinner.Value = 45;
            app.PreviewButton = uibutton(app.StructureElementTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.SizeSpinnerLabel = uilabel(app.StructureElementTab);
            app.SizeSpinnerLabel.Text = 'Size';
            app.SizeSpinner = uispinner(app.StructureElementTab);
            app.SizeSpinner.Limits = [0 Inf];
            app.RemovinglittlenoiseLabel = uilabel(app.StructureElementTab);
            app.RemovinglittlenoiseLabel.Text = 'Removing little noise';
            app.FlatfieldTab = uitab(app.TabGroup);
            app.FlatfieldTab.Title = 'Flat-field';
            app.SigmaSpinnerLabel = uilabel(app.FlatfieldTab);
            app.SigmaSpinnerLabel.Text = 'Sigma';
            app.SigmaSpinner = uispinner(app.FlatfieldTab);
            app.SigmaSpinner.Value = 30;
            app.ApplyFlatFieldButton = uibutton(app.FlatfieldTab, 'push');
            app.ApplyFlatFieldButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFlatFieldButtonPushed, true);
            app.ApplyFlatFieldButton.Text = 'Apply';
            app.PreviewFlatFieldButton = uibutton(app.FlatfieldTab, 'push');
            app.PreviewFlatFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewFlatFieldButtonPushed, true);
            app.PreviewFlatFieldButton.Text = 'Preview';
            app.BrightenTab = uitab(app.TabGroup);
            app.BrightenTab.Title = 'Brighten';
            app.brightnessSpinnerLabel = uilabel(app.BrightenTab);
            app.brightnessSpinnerLabel.Text = 'brightness';
            app.brightnessSpinner = uispinner(app.BrightenTab);
            app.brightnessSpinner.Step = 0.1;
            app.brightnessSpinner.Limits = [0 1];
            app.brightnessSpinner.Value = 0.5;
            app.AlphaBlendCheckBox = uicheckbox(app.BrightenTab);
            app.AlphaBlendCheckBox.Text = 'Alpha Blend';
            app.PreviewBrightnessButton = uibutton(app.BrightenTab, 'push');
            app.PreviewBrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewBrightnessButtonPushed, true);
            app.PreviewBrightnessButton.Text = 'Preview';
            app.ApplyBrightnessButton = uibutton(app.BrightenTab, 'push');
            app.ApplyBrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyBrightnessButtonPushed, true);
            app.ApplyBrightnessButton.Text = 'Apply';
            app.CropButton = uibutton(app.LoadImagePanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Enable = 'off';
            app.CropButton.Text = 'Crop';
            app.ResetButton = uibutton(app.LoadImagePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.SaveEnhanceImageButton = uibutton(app.LoadImagePanel, 'push');
            app.SaveEnhanceImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEnhanceImageButtonPushed, true);
            app.SaveEnhanceImageButton.Enable = 'off';
            app.SaveEnhanceImageButton.Text = {'Save Image'; ''};
            app.SaveSSPImageButton = uibutton(app.KhmerPalmLeafManuscriptBinarizationUIFigure, 'push');
            app.SaveSSPImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSSPImageButtonPushed, true);
            app.SaveSSPImageButton.Enable = 'off';
            app.SaveSSPImageButton.Text = 'Save Image';
            app.CloseButton = uibutton(app.KhmerPalmLeafManuscriptBinarizationUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Button = uibutton(app.KhmerPalmLeafManuscriptBinarizationUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '▽';
            app.Button_2 = uibutton(app.KhmerPalmLeafManuscriptBinarizationUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '△';
            % Show the figure after all components are created
            app.KhmerPalmLeafManuscriptBinarizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KhmerPalmLeafManuscriptBinarizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KhmerPalmLeafManuscriptBinarizationUIFigure)
        end
    end
end